classdef waterTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartButton             matlab.ui.control.Button
        Switch                  matlab.ui.control.Switch
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        DrinkingWaterTestLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             update();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
             
    % Set the background and text colors based on the mode
    if strcmp(value, 'Night') % Night mode
        % Set background color to black
        app.UIFigure.Color = [0 0 0];
        
        % Set text color to white
        app.Label.FontColor = [1 1 1];
        app.DrinkingWaterTestLabel.FontColor = [1 1 1];
        % Add more UI components as needed
    else % Day mode
        % Set background color to white (or any other color you prefer)
        app.UIFigure.Color = [1 1 1];
        
        % Set text color to black
        app.Label.FontColor = [0 0 0];
        app.DrinkingWaterTestLabel.FontColor = [0 0 0];
        % Add more UI components as needed
    end
        end

        % Callback function
        function ImportFileLogButtonSizeChanged(app, event)
           % Get the current position of the ImportFileLogButton
  % Prompt the user to select an Excel file
    [filename, filepath] = uigetfile({'*.xlsx', '*.xls'}, 'Select Excel File');
    
    % Check if the user selected a file
    if ischar(filename)
        % Read the data from the Excel file
        imported_data = readtable(fullfile(filepath, filename));
        
        % Process the imported data as needed
        % (You can add your data processing logic here)
        
        % Display a message indicating successful file import
        msgbox('File imported successfully.', 'Success', 'modal');
    else
        % Display a message indicating that no file was selected
        msgbox('No file selected.', 'Warning', 'warn', 'modal');
    end
        end

        % Callback function: not associated with a component
        function ImportdataButtonValueChanged(app, event)
  value = app.ImportdataButton.Value;
  % Get the current position of the ImportFileLogButton
  % Prompt the user to select an Excel file
    [filename, filepath] = uigetfile({'*.xlsx', '*.xls'}, 'Select Excel File');
    
    % Check if the user selected a file
    if ischar(filename)
        % Read the data from the Excel file
        imported_data = readtable(fullfile(filepath, filename));
        
        % Process the imported data as needed
        % i will add my data evaluation here danico don't forget 
        
        % Display a message indicating successful file import
        msgbox('File imported successfully.', 'Success', 'modal');
    else
        % Display a message indicating that no file was selected
        msgbox('No file selected.', 'Warning', 'warn', 'modal');
    end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'water-quality.jpg');
            app.DrinkingWaterTestLabel = uilabel(app.UIFigure);
            app.DrinkingWaterTestLabel.Text = 'Drinking Water Test';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'html';
            app.Label.Text = 'This app is Design to test if water this allow to drink or not base on your data test';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Night', 'Days'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Night';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waterTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end